classdef MainAcquisition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Panel1                      matlab.ui.container.Panel
        HomerDropDownLabel          matlab.ui.control.Label
        HomerDropDown               matlab.ui.control.DropDown
        ThoraxDropDownLabel         matlab.ui.control.Label
        ThoraxDropDown              matlab.ui.control.DropDown
        HomerSwitch                 matlab.ui.control.Switch
        ThoraxSwitch                matlab.ui.control.Switch
        SensorizedSideListBoxLabel  matlab.ui.control.Label
        SensorizedSideListBox       matlab.ui.control.ListBox
        Panel2                      matlab.ui.container.Panel
        AutoStopSwitch              matlab.ui.control.Switch
        AutoStopsEditFieldLabel     matlab.ui.control.Label
        AutoStopsEditField          matlab.ui.control.EditField
        SubjectEditFieldLabel       matlab.ui.control.Label
        SubjectEditField            matlab.ui.control.EditField
        GoldStandardEditFieldLabel  matlab.ui.control.Label
        GoldStandardEditField       matlab.ui.control.EditField
        CommentsTextAreaLabel       matlab.ui.control.Label
        CommentsTextArea            matlab.ui.control.TextArea
        ScanButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        PlotButton                  matlab.ui.control.Button
        HomerUIAxes                 matlab.ui.control.UIAxes
        ThoraxUIAxes                matlab.ui.control.UIAxes
        ISOButton                   matlab.ui.control.Button
        PIEButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        SensorsFound = 0;
        ExelObj
        Saved = false;
        Calibration = eye(3);
    end
    
    methods (Static, Access = private)
        
        function SamplingFcn(obj)
            % getting init and stop
            init = obj.LastFrame+1;
            stop = height(obj.ExelData);
            
            % computing indexes and time
            ind = (init : stop)';
            time = (ind-1)/obj.SamplingFrequency;
            
            % setting new XLim if necessary
            if time(end,1) > obj.ExelFigure.Axes.XLim(1,2)
                obj.ExelFigure.Axes.XLim = obj.ExelFigure.Axes.XLim + 10;
                clearpoints(obj.ExelFigure.Line)
            end
            
            % computing Angle
            angle = projection(obj,ind);
            
            % adding points to the animatedline
            addpoints(obj.ExelFigure.Line,time,angle);
            
            % showing now new values
            drawnow limitrate
            
            % updating LastFrame
            obj.LastFrame = stop;
        end
        
    end
    
    methods (Access = private)
        
        function saveRoutine(app)
            % putting shadow on components
            uishadow(app,'Saving...')
            
            % getting current script directory
            csd = fileparts(mfilename('fullpath'));
            
            % computing data dir
            datapath = fullfile(csd, ...
                char(datetime('now','Format','yyyy-MM-dd')));
            
            % checking datapath existance
            touch(datapath);
            
            % getting Subject
            Subject = app.ExelObj(1,1).Subject;
            
            % setting default filename
            ProgrNum = '01';
            filename = [Subject,'_',ProgrNum,'.mat'];
            while exist(fullfile(datapath,filename),'file')
                ProgrNum = num2str(str2double(ProgrNum)+1);
                if length(ProgrNum)<2
                    ProgrNum = ['0',ProgrNum]; %#okAGROW
                end
                filename = [Subject,'_',ProgrNum,'.mat'];
            end
            
            % getting Exel obj
            obj = ExelCompressed(app.ExelObj); %#okAGROW

            % saving
            save(fullfile(datapath,filename),'obj');
            
            % setting saved property to true
            app.Saved = true;
            
            % removing shadow from components
            pause(2)
            uishadow(app)
        end
        
        function enable(app)
            % getting app properties
            appProp = setdiff(properties(app), ...
                {'StartButton','StopButton','SaveButton', ...
                'PlotButton','ISOButton','PIEButton'});
            
            % setting exceptions
            exceptions = {'HomerDropDown';'ThoraxDropDown';'AutoStopEditField'};
            
            % cycling to enable properties
            for i = 1:numel(appProp)
                cProp = appProp{i,1};
                if ismember(cProp,exceptions)
                    temp = strrep(cProp,'DropDown','');
                    temp = strrep(temp,'EditField','');
                    cSwitch = [temp,'Switch'];
                    if strcmp(app.(cSwitch).Value,'On')
                        app.(cProp).Enable = 'on';
                    end
                else
                    try %#okAGROW
                        app.(cProp).Enable = 'on';
                    end
                end
            end
            
            % StartButton
            hValue = string(app.HomerDropDown.Value);
            tValue = string(app.ThoraxDropDown.Value);
            if any([hValue,tValue]~='Select...')
                app.StartButton.Enable = 'on';
            end
            
            % Save and Plot Buttons
            if isa(app.ExelObj,'Exel') && ...
                    height(app.ExelObj(1).ExelData)>0
                app.PlotButton.Enable = 'on';
                app.ISOButton.Enable = 'on';
                app.PIEButton.Enable = 'on';
                if not(app.Saved)
                    app.SaveButton.Enable = 'on';
                end
            end
        end
        
        function disable(app)
            appProp = properties(app);
            for i = 1:numel(appProp)
                cProp = appProp{i,1};
                if not(isa(app.(cProp),'matlab.ui.control.Label'))
                    try %#okAGROW
                        app.(cProp).Enable = 'off';
                    end
                end
            end
        end
        
        function reset(app)
            if isa(app.ExelObj,'Exel')
                for i = 1:numel(app.ExelObj)
                    reset(app.ExelObj(i,1))
                    delete(app.ExelObj(i,1).ExelFigure.Line)
                    app.ExelObj(i,1).ExelFigure.Axes.XLim = [0,10];
                end
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = centerfig('screen',app.UIFigure);
            uishadow(app,{'Checking old','Bluetooth instances...'})
            clc
            instruments = instrfindall;
            for i = 1:numel(instruments)
                try %#okAGROW
                    fclose(instruments(i));
                end
            end
            pause(2)
            ScanButtonPushed(app)
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            % disabling all (except for labels)
            disable(app)
            
            % resetting ExelData and plots
            reset(app)
            
            % resetting ExelNames and Segments
            app.HomerDropDown.Items = {'Select...'};
            app.ThoraxDropDown.Items = {'Select...'};
            app.HomerSwitch.Value = 'Off';
            app.ThoraxSwitch.Value = 'Off';
            
            % putting shadow on components
            uishadow(app,{'Scanning for';'Bluetooth devices...'})
            
            % getting bluetooth info
            try
                btInfo = instrhwinfo('bluetooth');
            catch ME
                uialert2(app.UIFigure,ME.message,'Bluetooth Error')
                app.ScanButton.Enable = 'on';
                return
            end
            
            % getting bluetooth devices friendly names
            btNames = btInfo.RemoteNames;
            if isempty(btNames), btNames = {}; end
            
            % getting friendly names length
            btLengs = cellfun(@length,btNames);
            
            % removing too short or too long names
            btNames(btLengs<5 & btLengs>10) = [];
            
            % finding friendly names who start with EXLs3
            btInits = cellfun(@(x)x(1:5),btNames,'UniformOutput',false);
            exelInd = cellfun(@isequal,btInits,repmat({'EXLs3'},size(btInits)));
            
            % getting exelnames
            exelNames = sort(btNames(exelInd));
            
            % setting the SensorsFound property
            app.SensorsFound = numel(exelNames);
            
            % enabling components if necessary
            if app.SensorsFound > 0
                % showing EXLs3 devices found
                uishadow(app,[exelNames;'had been found!'])
                
                % setting dropdown items
                Items = [{'Select...'};exelNames];
                app.HomerDropDown.Items = Items;
                app.ThoraxDropDown.Items = Items;
                
                % removing shadow from components after 2 sec
                pause(2)
                enable(app)
                uishadow(app)
            else
                % removing shadow from components
                uishadow(app)
                
                % alerting user
                message = ['No EXLs3 sensor found. ', ...
                    'Be sure that the devices ', ...
                    'you want to use are turned on, ', ...
                    'then retry the scan routine.'];
                title = 'No Sensor Found';
                uialert2(app.UIFigure,message,title,'Icon','Warning')
                
                % enabling only scan button
                app.ScanButton.Enable = 'on';
            end
        end

        % Value changed function: HomerSwitch
        function HomerSwitchValueChanged(app, event)
            switch app.HomerSwitch.Value
                case 'On'
                    if app.SensorsFound == 1
                        app.ThoraxSwitch.Value = 'Off';
                        app.ThoraxSwitch.Enable = 'on';
                        app.ThoraxDropDown.Value = 'Select...';
                        app.ThoraxDropDown.Enable = 'off';
                    end
                    app.HomerDropDown.Enable = 'on';
                case 'Off'
                    if ~strcmp(app.HomerDropDown.Value,'Select...')
                        app.ThoraxDropDown.Items = ['Select...'; ...
                            sort([app.ThoraxDropDown.Items(2:end); ...
                            {app.HomerDropDown.Value}])];
                    end
                    app.ThoraxSwitch.Enable = 'on';
                    app.HomerDropDown.Value = 'Select...';
                    app.HomerDropDown.Enable = 'off';
            end
            
            % checking dropdown values:
            % if both are select, then disable StartButton
            hValue = string(app.HomerDropDown.Value);
            tValue = string(app.ThoraxDropDown.Value);
            if all([hValue,tValue]=='Select...')
                app.StartButton.Enable = 'off';
            end
        end

        % Value changed function: ThoraxSwitch
        function ThoraxSwitchValueChanged(app, event)
            switch app.ThoraxSwitch.Value
                case 'On'
                    if app.SensorsFound == 1
                        app.HomerSwitch.Value = 'Off';
                        app.HomerSwitch.Enable = 'on';
                        app.HomerDropDown.Value = 'Select...';
                        app.HomerDropDown.Enable = 'off';
                    end
                    app.ThoraxDropDown.Enable = 'on';
                case 'Off'
                    if ~strcmp(app.ThoraxDropDown.Value,'Select...')
                        app.HomerDropDown.Items = ['Select...'; ...
                            unique([app.HomerDropDown.Items(2:end); ...
                            {app.ThoraxDropDown.Value}])];
                    end
                    app.HomerSwitch.Enable = 'on';
                    app.ThoraxDropDown.Value = 'Select...';
                    app.ThoraxDropDown.Enable = 'off';
            end
            
            % checking dropdown values:
            % if both are select, then disable StartButton
            hValue = string(app.HomerDropDown.Value);
            tValue = string(app.ThoraxDropDown.Value);
            if all([hValue,tValue]=='Select...')
                app.StartButton.Enable = 'off';
            end
        end

        % Value changed function: HomerDropDown
        function HomerDropDownValueChanged(app, event)
            % managing DropDown items
            cValue = string(event.Value);
            pValue = string(event.PreviousValue);
            if app.SensorsFound>1
                if pValue == 'Select...' && cValue == 'Select...'
                    % nothing happens
                elseif pValue == 'Select...' && cValue ~= 'Select...'
                    app.ThoraxDropDown.Items = setdiff( ...
                        app.ThoraxDropDown.Items, ...
                        char(cValue),'stable');
                elseif pValue ~= 'Select...' && cValue == 'Select...'
                    app.ThoraxDropDown.Items = ['Select...'; ...
                        sort([app.ThoraxDropDown.Items(2:end); ...
                        {char(pValue)}])];
                elseif pValue ~= 'Select...' && cValue ~= 'Select...'
                    app.ThoraxDropDown.Items = setdiff( ...
                        app.ThoraxDropDown.Items, ...
                        char(cValue),'stable');
                    app.ThoraxDropDown.Items = ['Select...'; ...
                        sort([app.ThoraxDropDown.Items(2:end); ...
                        {char(pValue)}])];
                end
            end
            
            % checking dropdown values to enable StartButton
            hValue = cValue;
            tValue = string(app.ThoraxDropDown.Value);
            if all([hValue,tValue]=='Select...')
                app.StartButton.Enable = 'off';
            else
                app.StartButton.Enable = 'on';
            end
        end

        % Value changed function: ThoraxDropDown
        function ThoraxDropDownValueChanged(app, event)
            % resetting calibration matrix
            app.Calibration = eye(3);
            
            % managing DropDown itmes
            cValue = string(event.Value);
            pValue = string(event.PreviousValue);
            if app.SensorsFound>1
                if pValue == 'Select...' && cValue == 'Select...'
                    % nothing happens
                elseif pValue == 'Select...' && cValue ~= 'Select...'
                    app.HomerDropDown.Items = setdiff( ...
                        app.HomerDropDown.Items, ...
                        char(cValue),'stable');
                elseif pValue ~= 'Select...' && cValue == 'Select...'
                    app.HomerDropDown.Items = ['Select...'; ...
                        sort([app.HomerDropDown.Items(2:end); ...
                        {char(pValue)}])];
                elseif pValue ~= 'Select...' && cValue ~= 'Select...'
                    app.HomerDropDown.Items = setdiff( ...
                        app.HomerDropDown.Items, ...
                        char(cValue),'stable');
                    app.HomerDropDown.Items = ['Select...'; ...
                        sort([app.HomerDropDown.Items(2:end); ...
                        {char(pValue)}])];
                end
            end
            
            % checking dropdown values to enable StartButton
            hValue = string(app.HomerDropDown.Value);
            tValue = cValue;
            if all([hValue,tValue]=='Select...')
                app.StartButton.Enable = 'off';
            else
                app.StartButton.Enable = 'on';
            end
        end

        % Value changed function: AutoStopSwitch
        function AutoStopSwitchValueChanged(app, event)
            switch app.AutoStopSwitch.Value
                case 'On'
                    app.AutoStopsEditField.Value = '10';
                    app.AutoStopsEditField.Enable = 'on';
                case 'Off'
                    app.AutoStopsEditField.Value = 'Never Ending';
                    app.AutoStopsEditField.Enable = 'off';
            end
        end

        % Value changed function: AutoStopsEditField
        function AutoStopsEditFieldValueChanged(app, event)
            value = round(str2double(app.AutoStopsEditField.Value));
            if value > 0
                app.AutoStopsEditField.Value = num2str(value);
            else
                message = 'AutoStop must be a numeric value greater than 0.';
                title = 'AutoStop Invalid Value';
                uialert2(app.UIFigure,message,title)
                app.AutoStopsEditField.Value = event.PreviousValue;
            end
        end

        % Value changed function: GoldStandardEditField
        function GoldStandardEditFieldValueChanged(app, event)
            value = round(str2double(app.GoldStandardEditField.Value));
            if value>=0 && value<=180
                app.GoldStandardEditField.Value = num2str(value);
            else
                message = 'GoldStandard must be a numeric value between 0 and 180.';
                title = 'GoldStandard Invalid Value';
                uialert2(app.UIFigure,message,title)
                app.GoldStandardEditField.Value = 'Not Specified';
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % disabling all
            disable(app)
            
            % reset ExelData and old plots
            reset(app)
            
            % putting a shadow on components
            uishadow(app,'Creating Exel Objects...')
            
            % getting ExelNames
            ExelNames = setdiff( ...
                {app.HomerDropDown.Value; ...
                app.ThoraxDropDown.Value}, ...
                'Select...','stable');
            
            % getting AutoStop
            if strcmp(app.AutoStopSwitch.Value,'Off')
                AutoStop = Inf;
            else
                AutoStop = str2double(app.AutoStopsEditField.Value);
            end
            
            % creating Exel array
            try
                app.ExelObj = Exel(ExelNames,'AutoStop',AutoStop);
            catch ME
                uishadow(app)
                uialert2(app.UIFigure,ME.message,'Bluetooth Error')
                enable(app)
                return
            end
            
            % getting homer and thorax index
            hPresence = ~strcmp(app.HomerDropDown.Value,'Select...');
            tPresence = ~strcmp(app.ThoraxDropDown.Value,'Select...');
            if hPresence==false && tPresence==false
                % impossible
            elseif hPresence==false && tPresence==true
                hIndex = []; tIndex = 1;
            elseif hPresence==true && tPresence==false
                hIndex = 1; tIndex = [];
            elseif hPresence==true && tPresence==true
                hIndex = 1; tIndex = 2;
            end
            
            % setting Segment
            set(app.ExelObj(hIndex),'Segment','Homer')
            set(app.ExelObj(tIndex),'Segment','Thorax')
            
            % setting UserData
            UserData.GoldStandard = str2double(app.GoldStandardEditField.Value);
            UserData.Comments = app.CommentsTextArea.Value;
            UserData.isHomerRight = true;
            set(app.ExelObj(hIndex),'UserData',UserData);
            set(app.ExelObj(tIndex),'UserData',UserData);
            
            % setting SamplingFcn
            set(app.ExelObj(hIndex),'SamplingFcn',@app.SamplingFcn)
            set(app.ExelObj(tIndex),'SamplingFcn',@app.SamplingFcn)
            
            % creating homer ExelFigure structure
            hFigure.Axes = app.HomerUIAxes;
            hFigure.Line = animatedline(app.HomerUIAxes,'Color',[1,0,0]);
            
            % creating thorax ExelFigure structure
            tFigure.Axes = app.ThoraxUIAxes;
            tFigure.Line = animatedline(app.ThoraxUIAxes,'Color',[0,0,1]);
            
            % setting both ExelFigures
            set(app.ExelObj(hIndex),'ExelFigure',hFigure)
            set(app.ExelObj(tIndex),'ExelFigure',tFigure)
            
            % getting Subject
            if strcmp(app.SubjectEditField.Value,'Not Specified')
                Subject = 'NS00';
            else
                Subject = app.SubjectEditField.Value;
            end
            
            % setting Subject
            set(app.ExelObj,'Subject',Subject)
            
            % setting prev calibration
            set(app.ExelObj(tIndex),'Calibration',app.Calibration)
            
            % determining if calibration routine is necessary
            isCalibrationNecessary = tPresence && isequal(app.Calibration,eye(3));
            
            % calibration routine
            if isCalibrationNecessary
                % removing shadow from components
                uishadow(app)
                
                % asking the user if ready
                selection = uiconfirm( ...
                    app.UIFigure, ...
                    sprintf(['In order to be accurate, it is necessary ', ...
                    'to calibrate the Thorax sensor. The calibration ', ...
                    'routine consists of 10 seconds in wich the subject ', ...
                    'must be standing still.\n\nAre you ready for it?']), ...
                    'Calibration Dialog', ...
                    'Options',{'Yes','Skip','Quit'}, ...
                    'DefaultOption',1, ...
                    'CancelOption',3 ...
                    );
                switch selection
                    case 'Yes'
                        % putting shadow on components
                        uishadow(app,'Connecting for calibration...')
                        
                        % getting right Exel obj
                        obj = app.ExelObj(tIndex);
                        
                        % connecting for calibration
                        try
                            connect(obj)
                        catch ME
                            uialert2(app.UIFigure,ME.message,'Bluetooth Error')
                            StopButtonPushed(app)
                            enable(app)
                            return
                        end
                        
                        % getting AutoStop and replacing it with 10 s
                        cAutoStop = get(obj,'AutoStop');
                        set(obj,'AutoStop',10)
                        
                        % launching calibration: calibration
                        uishadow(app,{'Calibration in...';'3'}),pause(1)
                        uishadow(app,{'Calibration in...';'2'}),pause(1)
                        uishadow(app,{'Calibration in...';'1'}),pause(1)
                        
                        % starting acquisition for calibration
                        uishadow(app,'Starting Calibration...')
                        try
                            start(obj)
                        catch ME
                            uialert2(app.UIFigure,ME.message,'Bluetooth Error')
                            StopButtonPushed(app)
                            enable(app)
                            return
                        end
                        
                        % waiting for the calibration end
                        uishadow(app,'Calibrating... Be still!')
                        waitfor(obj,'AcquisitionStatus','off')
                        
                        % ending the calibration
                        uishadow(app,'Calibration Done!')
                        
                        % computing mean angle
                        a = mean(projection(obj));
                        
                        % computing rotation matrix
                        R = [1,0,0;0,cosd(a),sind(a);0,-sind(a),cosd(a)];
                        
                        % setting calibration property
                        app.Calibration = R;
                        
                        % setting the new calibration matrix on Exel object
                        set(obj,'Calibration',R)
                        
                        % resetting AutoStop
                        set(obj,'AutoStop',cAutoStop)
                        
                        % resetting ExelData and LastFrame
                        reset(obj)
                        
                        % resetting plots
                        clearpoints(obj.ExelFigure.Line)
                        obj.ExelFigure.Axes.XLim = [0,10];
                        
                        % making a rest
                        pause(2)
                    case 'Skip'
                        % nothing happens
                    case 'Quit'
                        StopButtonPushed(app)
                        enable(app)
                        return
                end
            end
            
            % connecting exel devices
            uishadow(app,'Connecting...')
            try
                connect(app.ExelObj)
            catch ME
                uialert2(app.UIFigure,ME.message,'Bluetooth Error')
                StopButtonPushed(app)
                enable(app)
                return
            end
            
            % enabling Stop Button
            app.StopButton.Enable = 'on';
            
            % starting acquisition
            try
                start(app.ExelObj)
            catch ME
                uialert2(app.UIFigure,ME.message,'Bluetooth Error')
                StopButtonPushed(app)
                enable(app)
                return
            end
            
            % removing shadow from components
            uishadow(app)
            
            % waiting for the acquisition end
            waitfor(app.ExelObj(end,1),'AcquisitionStatus','off')
            if strcmp(app.ExelObj(end,1).ConnectionStatus,'open')
                StopButtonPushed(app)
            end
            
            % in any case, disabling Stop Button
            app.StopButton.Enable = 'off';
            
            % asking the user for saving
            selection = uiconfirm( ...
                app.UIFigure, ...
                'Do you want to save the entire data collected?', ...
                'Confirm Save', ...
                'Options',{'Yes';'No'}, ...
                'DefaultOption',1, ...
                'CancelOption',2 ...
                );
            
            % saving if necessary
            if strcmp(selection,'Yes')
                saveRoutine(app)
            end
            
            % enabling components
            enable(app)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if isa(app.ExelObj,'Exel')
                uishadow(app,'Disconnecting...')
                try
                    disconnect(app.ExelObj)
                    uishadow(app)
                catch ME
                    uishadow(app)
                    uialert2(app.UIFigure,ME.message,'Bluetooth Error')
                end
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % creating figure
            figure('Position',get(0,'ScreenSize'))
            
            % sinchronization routine
            % ask marta
            
            % filtering
            
            
            % computing angles
            theta_hum = projection(app.ExelObj(1,1));
            theta_thx = projection(app.ExelObj(2,1));
            h = min(numel(angleH),numel(angleT));
            angleH = angleH(1:h,1);
            angleT = angleT(1:h,1);
            angleH = angleH + angleT;
            
            % getting axes number
            nAxes = numel(app.ExelObj);
            
            % preallocating axes
            ax(nAxes,1) = axes();
            
            % cycling on Exel objects
            for i=1:nAxes
                % getting Segment
                switch app.ExelObj(i).Segment
                    case 'Homer'
                        Title = 'Homer';
                        Color = [1,0,0];
                        YLim = [0,180];
                        YTick = [0,30,60,90,120,150,180];
                        angle = angleH;
                    case 'Thorax'
                        Title = 'Thorax';
                        Color = [0,0,1];
                        YLim = [-90,90];
                        YTick = [-90,-60,-30,0,30,60,90];
                        angle = angleT;
                end
                
                % getting time and angles
                t0 = 1/app.ExelObj(i,1).SamplingFrequency;
                h = numel(angle);
                time = linspace(0,t0*(h-1),h);
                
                % plotting
                ax(i,1) = subplot(nAxes,1,i);
                plot(time,angle,'Color',Color)
                
                % setting some other properties
                xlabel('time (s)')
                ylabel('degrees (\circ)')
                ylim(YLim)
                title(Title)
                set(ax(i,1),'YTick',YTick)
                set(ax(i,1),'XMinorGrid','on')
                set(ax(i,1),'YMinorGrid','on')
                set(ax(i,1),'XMinorTick','on')
                set(ax(i,1),'YMinorTick','on')
                set(ax(i,1),'FontSize',20)
            end
            
            % linking axes
            linkaxes(ax,'x')
        end

        % Value changed function: SubjectEditField
        function SubjectEditFieldValueChanged(app, event)
            value = app.SubjectEditField.Value;
            if not(length(value)==4 && ...
                    all(isstrprop(value(1:2),'alpha')) && ...
                    all(isstrprop(value(3:4),'digit')))
                message = sprintf(['Subject value must be 4 character', ...
                    ' long and composed by:\n', ...
                    '- first and second characters must be the', ...
                    ' initials of the subject name;\n', ...
                    '- third and fourth characters must be the', ...
                    ' last two digits of the subject birth year.\n\n', ...
                    'Example:\nJS94 for John Smith, born in 1994.']);
                title = 'Subject Invalid Value';
                uialert2(app.UIFigure,message,title)
                app.SubjectEditField.Value = 'Not Specified';
            else
                % resetting calibration matrix
                app.Calibration = eye(3);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveRoutine(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            StopButtonPushed(app)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MainAcquisition';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel1 = uipanel(app.UIFigure);
            app.Panel1.Title = 'EXEL NAMES & SEGMENTS';
            app.HomerDropDownLabel = uilabel(app.Panel1);
            app.HomerDropDownLabel.Text = 'Homer';
            app.HomerDropDown = uidropdown(app.Panel1);
            app.HomerDropDown.Items = {'Select...'};
            app.HomerDropDown.ValueChangedFcn = createCallbackFcn(app, @HomerDropDownValueChanged, true);
            app.HomerDropDown.Enable = 'off';
            app.HomerDropDown.Value = 'Select...';
            app.ThoraxDropDownLabel = uilabel(app.Panel1);
            app.ThoraxDropDownLabel.Text = 'Thorax';
            app.ThoraxDropDown = uidropdown(app.Panel1);
            app.ThoraxDropDown.Items = {'Select...'};
            app.ThoraxDropDown.ValueChangedFcn = createCallbackFcn(app, @ThoraxDropDownValueChanged, true);
            app.ThoraxDropDown.Enable = 'off';
            app.ThoraxDropDown.Value = 'Select...';
            app.HomerSwitch = uiswitch(app.Panel1, 'slider');
            app.HomerSwitch.ValueChangedFcn = createCallbackFcn(app, @HomerSwitchValueChanged, true);
            app.HomerSwitch.Enable = 'off';
            app.ThoraxSwitch = uiswitch(app.Panel1, 'slider');
            app.ThoraxSwitch.ValueChangedFcn = createCallbackFcn(app, @ThoraxSwitchValueChanged, true);
            app.ThoraxSwitch.Enable = 'off';
            app.SensorizedSideListBoxLabel = uilabel(app.Panel1);
            app.SensorizedSideListBoxLabel.Text = {'Sensorized'; 'Side'};
            app.SensorizedSideListBox = uilistbox(app.Panel1);
            app.SensorizedSideListBox.Items = {'Right', 'Left'};
            app.SensorizedSideListBox.Enable = 'off';
            app.SensorizedSideListBox.Value = 'Right';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'OPTIONS';
            app.AutoStopSwitch = uiswitch(app.Panel2, 'slider');
            app.AutoStopSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoStopSwitchValueChanged, true);
            app.AutoStopSwitch.Enable = 'off';
            app.AutoStopsEditFieldLabel = uilabel(app.Panel2);
            app.AutoStopsEditFieldLabel.Text = 'AutoStop (s)';
            app.AutoStopsEditField = uieditfield(app.Panel2, 'text');
            app.AutoStopsEditField.ValueChangedFcn = createCallbackFcn(app, @AutoStopsEditFieldValueChanged, true);
            app.AutoStopsEditField.Enable = 'off';
            app.AutoStopsEditField.Value = 'Never Ending';
            app.SubjectEditFieldLabel = uilabel(app.Panel2);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.Panel2, 'text');
            app.SubjectEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectEditFieldValueChanged, true);
            app.SubjectEditField.Enable = 'off';
            app.SubjectEditField.Value = 'Not Specified';
            app.GoldStandardEditFieldLabel = uilabel(app.Panel2);
            app.GoldStandardEditFieldLabel.Text = 'GoldStandard';
            app.GoldStandardEditField = uieditfield(app.Panel2, 'text');
            app.GoldStandardEditField.ValueChangedFcn = createCallbackFcn(app, @GoldStandardEditFieldValueChanged, true);
            app.GoldStandardEditField.Enable = 'off';
            app.GoldStandardEditField.Value = 'Not Specified';
            app.CommentsTextAreaLabel = uilabel(app.Panel2);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.CommentsTextArea = uitextarea(app.Panel2);
            app.CommentsTextArea.Enable = 'off';
            app.ScanButton = uibutton(app.UIFigure, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Icon = 'bluetooth_24.png';
            app.ScanButton.Enable = 'off';
            app.ScanButton.Text = 'Scan';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'run_24.png';
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'stop_24.png';
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'save_24.png';
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Icon = 'plot_24.png';
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.HomerUIAxes = uiaxes(app.UIFigure);
            title(app.HomerUIAxes, 'Homer')
            xlabel(app.HomerUIAxes, 'time (s)')
            ylabel(app.HomerUIAxes, 'degrees (\circ)')
            app.HomerUIAxes.XLim = [0 10];
            app.HomerUIAxes.YLim = [0 180];
            app.HomerUIAxes.YTick = [0 30 60 90 120 150 180];
            app.ThoraxUIAxes = uiaxes(app.UIFigure);
            title(app.ThoraxUIAxes, 'Thorax')
            xlabel(app.ThoraxUIAxes, 'time (s)')
            ylabel(app.ThoraxUIAxes, 'degrees (\circ)')
            app.ThoraxUIAxes.XLim = [0 10];
            app.ThoraxUIAxes.YLim = [-90 90];
            app.ThoraxUIAxes.YTick = [-90 -60 -30 0 30 60 90];
            app.ISOButton = uibutton(app.UIFigure, 'push');
            app.ISOButton.Icon = 'iso_24.png';
            app.ISOButton.Enable = 'off';
            app.ISOButton.Text = 'ISO';
            app.PIEButton = uibutton(app.UIFigure, 'push');
            app.PIEButton.Icon = 'pie_24.png';
            app.PIEButton.Enable = 'off';
            app.PIEButton.Text = 'PIE';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MainAcquisition

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end